classdef Ass2_2_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RangeofxabEditField_2           matlab.ui.control.EditField
        RangeofxabEditField_2Label      matlab.ui.control.Label
        PDFofXEditField_2               matlab.ui.control.EditField
        PDFofXEditField_2Label          matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        NumofquantizationpointsSpinner  matlab.ui.control.Spinner
        NumofquantizationpointsSpinnerLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
  
    x = linspace(-5, 5, 1000);
pdf = ones(size(x)) / 10; % Distribuzione uniforme
    formulaStringa = app.PDFofXEditField_2.Value;
    formulaStringa = strrep(formulaStringa, '^', '.^');
    formulaStringa = strrep(formulaStringa, '*', '.*');
    formulaStringa = strrep(formulaStringa, '/', './');
    disp(['Formula: ', formulaStringa]);

    % Legge il range da input
    rangeText = app.RangeofxabEditField_2.Value; % Input in formato "-10,10"
    rangeValues = split(rangeText, ',');
    nIniziale = str2double(rangeValues{1});
    nFinale = str2double(rangeValues{2});
    range = [nIniziale, nFinale]; % Range come array

    % Legge il numero di punti di quantizzazione
    nq = app.NumofquantizationpointsSpinner.Value;

    pq=[-4,+4]
    
    max_iter=10;

    for iter = 1:max_iter
        boundaries = [(pq(1:end-1) + pq(2:end)) / 2];

 % Visualizza PDF e regioni
    figure; hold on;
    plot(x, pdf, 'b', 'LineWidth', 2);
    for b = boundaries
        xline(b, '--r'); % Confini di Voronoi
    end
    scatter(pq, zeros(size(pq)), 'ko', 'filled'); % Centroidi
    title(['Iterazione ', num2str(iter)]);
    hold off; pause(1);
    
    % Calcola nuovi centroidi
    new_pq = zeros(size(pq));
    for i = 1:length(pq)
        if i == 1
            region = x < boundaries(1);
        elseif i == length(pq)
            region = x >= boundaries(end);
        else
            region = x >= boundaries(i-1) & x < boundaries(i);
        end
        new_pq(i) = mean(x(region));
    end
    
    % Controlla convergenza
    if max(abs(new_pq- pq)) < 1e-6
        break;
    end
    pq = new_pq;
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.NumofquantizationpointsSpinnerLabel = uilabel(app.UIFigure);
            app.NumofquantizationpointsSpinnerLabel.Text = 'Num. of quantization points';
            app.NumofquantizationpointsSpinner = uispinner(app.UIFigure);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PDFofXEditField_2Label = uilabel(app.UIFigure);
            app.PDFofXEditField_2Label.Text = 'PDF of X';
            app.PDFofXEditField_2 = uieditfield(app.UIFigure, 'text');
            app.PDFofXEditField_2.Value = 'exp(-x^2/2)';
            app.RangeofxabEditField_2Label = uilabel(app.UIFigure);
            app.RangeofxabEditField_2Label.Text = 'Range of x(a,b)';
            app.RangeofxabEditField_2 = uieditfield(app.UIFigure, 'text');
            app.RangeofxabEditField_2.Value = '-10,10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ass2_2_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end