classdef ex3pulito < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        HuffmanRateEditField            matlab.ui.control.EditField
        HuffmanRateEditFieldLabel       matlab.ui.control.Label
        EntropyRateEditField            matlab.ui.control.EditField
        EntropyRateEditFieldLabel       matlab.ui.control.Label
        RECALCULATEButton               matlab.ui.control.Button
        EXITButton                      matlab.ui.control.Button
        GroupingorderSpinner            matlab.ui.control.Spinner
        GroupingorderSpinnerLabel       matlab.ui.control.Label
        TextTextArea                    matlab.ui.control.TextArea
        TextTextAreaLabel               matlab.ui.control.Label
        TextentropyratecalculatorLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        % FUNCTION: count the # of characters in the text
        function numberChar = countChar(~, text)
            numberChar = strlength(text);
        end

        % FUNCTION: add blank spaces at the end if needed
        function textFinal = addSpace(app, text, numberChar, groupingOrder)
            if mod(numberChar, groupingOrder) ~= 0
                spaceToAdd = 0;
                spaceToAdd = groupingOrder - mod(numberChar, groupingOrder);
                textFinal = strcat(text, repmat('@', 1, spaceToAdd));
                numberChar = numberChar + spaceToAdd;
                textFinal = strrep(textFinal, '@', ' ');
                app.TextTextArea.Value = textFinal;
                textFinal = replace(textFinal, newline, ' ');
            else 
                textFinal = text;
                textFinal = replace(textFinal, newline, ' ');
            end
        end

        % FUNCTION: create groups of characters 
        function [groupForChar, numGroups] = groupCostruction (~, textFinal, numberChar, groupingOrder)
            numGroups = ceil(numberChar/groupingOrder);
            groupForChar = cell(1, numGroups);
            for i = 1:1:numGroups
                start_index = (i - 1) * groupingOrder + 1;
                end_index = min(i * groupingOrder, strlength(textFinal));
                groupForChar{i} = extractBetween(textFinal, start_index, end_index);
            end
        end

        % FUNCTION: count the frequency for each group of characters
        function frequency = arrayOfChar(~, groupForChar)
            frequency = zeros(1, length(groupForChar));
            for i = 1:length(groupForChar)
                for m = 1:length(groupForChar)
                    if isequal(groupForChar{i}, groupForChar{m})
                        frequency(i) = frequency(i) + 1; 
                    end
                end
            end

        end

        % FUNCTION: compute the probability for each group of characters 
        function probability = probOfGroup(~, frequency, numGroups)
            for i = 1: length(frequency)
                probability(i) = frequency(i)/numGroups;
            end
        end

        % FUNCTION: compute the entropy
        function entropyRate = entropyCalculate(~, probability)
            entropyRate = -sum(probability .* log2(probability));
        end
   
        % FUNCTION: dispay the 4 highest probability values in the table
        function displayTopProbabilities(app, groups, probabilities)
            [sortedProbabilities, sortIdx] = sort(probabilities, 'descend');
            sortedGroups = groups(sortIdx);

            numElementsToDisplay = min(4, length(groups));
        
            topGroups = sortedGroups(1:numElementsToDisplay);
            topProbabilities = sortedProbabilities(1:numElementsToDisplay);

            topGroupsWithQuotes = cellfun(@(x) ['"' x '"'], topGroups, 'UniformOutput', false);
            topProbabilitiesFormatted = arrayfun(@(x) sprintf('%.8f', x), topProbabilities, 'UniformOutput', false);
            app.UITable.Data = [topGroupsWithQuotes', topProbabilitiesFormatted'];
        end

       % FUNCTION: recursive function to compute Huffman Rate
       function huffmanRate = calculateHuffmanRate(app, probabilities)
        
            % if there is just 1 probability (empty text) return HR = 1
            if length(probabilities) == 1
                huffmanRate = 0;
                return;
            end
            
            % step 1: sort the propabilities
            probabilities = sort(probabilities);
            
            % step 2: select the 2 lowest probabilities and sum them
            p1 = probabilities(1);
            p2 = probabilities(2);
            newProb = p1 + p2;
            
            % step 3: remove the lowest 2 probabilities (already used) and add the new probability (the sum)
            probabilities = probabilities(3:end);
            probabilities = [probabilities, newProb]; 
            
            % step 4: call again the function with the new probability array
            huffmanRate = calculateHuffmanRate(app, probabilities);

            huffmanRate = huffmanRate + (p1 * 1) + (p2 * 1); 
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: RECALCULATEButton
        function RECALCULATEButtonPushed(app, event)
            text = app.TextTextArea.Value;
            groupingOrder = app.GroupingorderSpinner.Value;

            % count the number of characters in the text
            numberChar = countChar(app, text);

            % add the spaces if necessary
            textFinal = addSpace(app, text, numberChar, groupingOrder);

            % form the groups
            [groupForChar, numGroups] = groupCostruction(app, textFinal, numberChar, groupingOrder);

            % compute frequency of each group
            frequency = arrayOfChar(app, groupForChar);

            % select the groups so they only appeare once and put them and
            % their correspondind indices in new arrays
            groupForChar = cellfun(@char, groupForChar, 'UniformOutput', false);
            [uniqueGroups, idx] = unique(groupForChar, 'stable');
            % find the corresponding frequencies
            uniqueFrequencies = frequency(idx);

            % compute the probability of the unique groups of characters
            probability = probOfGroup(app, uniqueFrequencies, numGroups);
            
            sumProbabilities = sum(probability);
        
            % compute entropy rate
            entropyRate = entropyCalculate(app, probability);
            app.EntropyRateEditField.Value = char(string(entropyRate/groupingOrder));
            
            % display the 4 higher probabilities in the table
            displayTopProbabilities(app, uniqueGroups, probability);

            % calculate and display the Huffman Rate
            huffRate = calculateHuffmanRate(app, probability);
            app.HuffmanRateEditField.Value = char(string(huffRate/groupingOrder));
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextentropyratecalculatorLabel = uilabel(app.UIFigure);
            app.TextentropyratecalculatorLabel.Text = 'Text entropy rate calculator';
            app.TextTextAreaLabel = uilabel(app.UIFigure);
            app.TextTextAreaLabel.Text = 'Text';
            app.TextTextArea = uitextarea(app.UIFigure);
            app.GroupingorderSpinnerLabel = uilabel(app.UIFigure);
            app.GroupingorderSpinnerLabel.Text = 'Grouping order ';
            app.GroupingorderSpinner = uispinner(app.UIFigure);
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.RECALCULATEButton = uibutton(app.UIFigure, 'push');
            app.RECALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @RECALCULATEButtonPushed, true);
            app.RECALCULATEButton.Text = 'RECALCULATE';
            app.EntropyRateEditFieldLabel = uilabel(app.UIFigure);
            app.EntropyRateEditFieldLabel.Text = 'Entropy Rate';
            app.EntropyRateEditField = uieditfield(app.UIFigure, 'text');
            app.HuffmanRateEditFieldLabel = uilabel(app.UIFigure);
            app.HuffmanRateEditFieldLabel.Text = 'Huffman Rate';
            app.HuffmanRateEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Word'; 'Probability'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex3pulito

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end